classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image1       matlab.ui.control.Image
        Image2       matlab.ui.control.Image
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button       matlab.ui.control.ToggleButton
        Button_2     matlab.ui.control.ToggleButton
        Button_3     matlab.ui.control.ToggleButton
        Button_4     matlab.ui.control.Button
        Label        matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
    end

    
    properties (Access = private)
        choosedimg % Description
    end
    
    methods (Access = private)
        
        function im = updateimage(app,imagefile)
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            app.Image1.ImageSource = im;
        end
        
        function update2(app, image)
            app.Image2.ImageSource = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.Image1.Visible = 'on';
            % Update the image and histograms
            app.choosedimg = updateimage(app, '14.jpg');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.Button.Value == true
                update2(app, cyberpunk(app.choosedimg));
            elseif app.Button_2.Value == true
                update2(app, sketch(app.choosedimg));
            else
                update2(app, carton(app.choosedimg));
            end
        end

        % Image clicked function: Image1
        function Image1Clicked(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            if (ischar(p))
                fname = [p f];
                app.choosedimg = updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked, true);
            app.Image1.ImageSource = '14.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '滤镜选项';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = '赛博朋克';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '速写';
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '动漫';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '转换';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '结果';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '点击选择图片';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '反应略慢  稍等';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end